classdef ourApplication < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Toolbar     matlab.ui.container.Toolbar
        Label_5     matlab.ui.control.Label
        Label_4     matlab.ui.control.Label
        OutPut      matlab.ui.control.Button
        DatePicker  matlab.ui.control.DatePicker
        LoadData    matlab.ui.control.Button
        Label       matlab.ui.control.Label
        step_num    matlab.ui.control.NumericEditField
        Cac         matlab.ui.control.Button
        TextArea    matlab.ui.control.TextArea
        Label_3     matlab.ui.control.Label
        image       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        acc % Description
        Property2 % Description
      pk_num       %： peak 检测到的峰值个数
      vy_num             %： valley检测到的波谷个数
      stp_num      %： step_num检测到的步数
      pk_ti        %:  波峰对应的时间点
      vy_ti        %： 波谷对应的时间点
      py_flg       %： 波峰标志
      %vy_flg       %:  波谷标志
    end
    
    methods (Access = private)
        
        function [len_y,pk_num, vy_num, stp_num, pk_ti, vy_ti, py_flg]=cac_stp_num(~,y,ap)
            dlt_y = diff(y);%后一项减前一项 Y=diff(X)计算的是 Y=[X(2)-X(1) X(3)-X(2) ... X(m)-X(m-1)]；
pk_num  = 0;
vy_num  = 0;
stp_num = 0;

len_y  = length(dlt_y);%dlt_y的个数
pk_flg       = zeros(len_y+1, 1);
vy_flg       = zeros(len_y+1, 1);
pk_ti        = zeros(len_y+1, 1);
vy_ti        = zeros(len_y+1, 1);
flg = 0;

for i = 2 :len_y
    if dlt_y(i-1) > 0 && dlt_y(i) < 0 && y(i) > ap % occour peak 检测出波峰
        pk_num = pk_num + 1;
        pk_acc(pk_num) = y(i);
        pk_pos(pk_num) = i;
        pk_flg(i) = y(i);
        if pk_num == 1 && vy_num == 0
              pk_flg(i) = y(i);
            flg = 1;
        elseif (flg == 1 && (pk_num - vy_num) > 1) || (flg == -1 && (pk_num - vy_num) >= 1)
            acc_df = pk_acc(pk_num)-pk_acc(pk_num-1);
            if acc_df > 0 
                pk_num = pk_num - 1;
                pk_flg(pk_pos(pk_num)) = 0;
                 pk_flg(i) = y(i);
                pk_pos(pk_num) = i;
            else
                pk_num = pk_num - 1;
                pk_flg(i) = 0;
            end
        end
    end
    if dlt_y(i-1) < 0 && dlt_y(i) > 0 && y(i) < ap*(-1) % occour vally 检测出波谷
        vy_num = vy_num + 1;
        vy_acc(vy_num) = y(i);
        vy_pos(vy_num) = i;
        vy_flg(i) = y(i);
        if vy_num == 1 && pk_num == 0
             vy_flg(i) = y(i);
            flg = -1;
        elseif (flg == 1 && (pk_num - vy_num) <= -1) || (flg == -1 && (pk_num - vy_num) < -1)
            acc_dfvy = vy_acc(vy_num) - vy_acc(vy_num-1);
            if acc_dfvy < 0
                vy_num = vy_num - 1;
                vy_flg(vy_pos(vy_num)) = 0;
                vy_pos(vy_num) = i;
            else
                vy_num = vy_num - 1;
                vy_flg(i) = 0;
            end
        end
    end
end
py_flg = [pk_flg, vy_flg];%py_flg表示波峰波谷标志
py_num = [pk_num,vy_num];%py_num表示波峰波谷个数
stp_num = min(py_num);%以波峰波谷值的最小值作为步数。


        end
        
        function [stp_len, dif_acc, s2] = cac_stp_len(~,stp_num, flg,acc)

%{
%   INPUTS
%       stp_num       :  stp_num_detection()函数计算得到的步数
%       py_flg        :  stp_num_detection()函数获取的波峰波谷幅值

%   OUTPUTS
%       stp_len      ： 估计得到的步长
%       Distance     ： 估计的步长累加值
%}

stp_len    = zeros(stp_num,1);
dif_acc    = zeros(stp_num,1);
pos1 = find(flg(:,1)~=0);  % pos1 表示波峰位置查找非零元素的位置，返回位置序号
pos2 = find(flg(:,2)~=0);  % pos1 表示波谷位置 
sumpk = 0;
distance = 0;
% % % % % % % % % % % % % % 
for i = 1:length(pos1)
        sumpk = sumpk + flg(pos1(i),1);
end
mPk = sumpk / length(pos1);
% K = 0.087 * mPk + 0.175
K = 0.087 * mPk + 0.5;%mPk为波峰平均数值
% K = 0.47;
% % % % % % % % % % % % % % 
for i = 1:stp_num
    sum = 0;
    for j = pos2(i):pos1(i)
       sum = sum +acc(j); %单步中采样点的合加速度
    end
    mAcc(i) = sum/(pos1(i)-pos2(i));%pos1(i)-pos2(i)是指单步加速度采样点个数 mAcc平均合加速度
end
% % % % % % % % % % % % % % %  
for i = 1:stp_num
    dif_acc(i) = abs(flg(pos1(i),1) - flg(pos2(i),2));% dif_acc 波峰值减去波谷值
%     K = 0.087 * dif_acc(i)/2 + 0.25;
    stp_len(i) = K * ((dif_acc(i)+1.3)^(1/4));
%      stp_len(i) = K * ((dif_acc(i))^(1/4));
   % stp_len1(i) = 0.8;
    stp_len2(i) = K * (mAcc(i)-flg(pos2(i),2))/dif_acc(i);%K乘以平均合加速度与波谷加速度的差除以极大极小差值
    stp_len3(i) = K * mAcc(i).^(1/3);%K乘以平均合加速度除以3);
    distance = distance + stp_len(i);%计算距离
end
            s2 = distance;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadData
        function LoadDataPushed(app, event)

           string datapath;
           datapath=uigetfile('*.txt');  
           app.acc=load(datapath);

        end

        % Button pushed function: Cac
        function CacButtonPushed(app, event)
           %进行数据预处理
            acc = app.acc(:,2:4);
            for i = 1:length(acc(:,1))
                acc(i,3)=acc(i,3)-9.7285;
            end
            %滤波器设计
            fs = 50; % Hz采样频率 
            n = 10; % 滤波器阶数
            f = [0 2 3 50]/fs; % 归一化频率向量,频率系数
            a = [1 1 0 0]; % 振幅向量，放大特性
            b = firls(n,f,a); % 采用FIR滤波器中的最优化设计法 b = firls(n,f,a) n为指定频率点幅度响应 f为频率点向量和幅值向量 a为期望的幅度
            [h,w] = freqz(b); % 数字滤波器的频率响应
            %计算加速度模值
            y = filter(b,1,acc); %y为加速度计z轴去除重力后的加速度值 
            len = length(y(:,1));
            a_module = zeros(len,1);
            for i = 1:len
             a_module(i) = sqrt(y(i,1)^2+y(i,2)^2+y(i,3)^2);
            end
            % step number detection 步数检测
             ap = -1.05; %幅值设置
            [len_y,app.pk_num, app.vy_num, app.stp_num, app.pk_ti, app.vy_ti, app.py_flg] = cac_stp_num(app,a_module, ap); %py_flg函数获得的波峰波谷值 stp_num 函数计算得到的步数 vy：valley波谷
            % step length detection 步长检测
  %          [stp_len, dif_acc, s2] = cac_stp_len(app,stp_num, py_flg,acc);
    %        stp_num=5;
    %       stp_len=5;
            app.step_num.Value=app.stp_num;
            
%             plot(app.image,1:length(stp_len),stp_len,"r");
%             xlabel(app.image,'步数/步');
%             ylabel(app.image,'步长/米');
%             title(app.image,'步数和步长');
            
%            stp_len=mean(stp_len)
%            app.step_size.Value=mean(stp_len);
            
            plot(app.image,1:len_y+1,app.py_flg(:,1),'r-.',1:len_y+1,app.py_flg(:,2),'g-.');
% plot(1:len_y+1,pk_flg,'r-.',1:len_y+1,vy_flg,'g-.');
% hold on
% plot(1:len_y+1,pk_flg,'bh')
xlabel(app.image,'加速度数');ylabel(app.image,'振幅');
title(app.image,'基于滤波器的步频检测');
legend(app.image,'波峰','波谷','三轴加速度矢量和');
        end

        % Button down function: image
        function imageButtonDown(app, event)
            
        end

        % Button pushed function: OutPut
        function OutPutPushed(app, event)
            mkdir('OutPut');
            result_num=[app.pk_num,app.vy_num,app.stp_num];
            result_ti=[app.pk_ti,app.vy_ti];
            result_flg=[app.py_flg(:,1),app.py_flg(:,2)];
            csvwrite('OutPut\result_num.csv',result_num);
            csvwrite('OutPut\result_ti.csv',result_ti);
            csvwrite('OutPut\result_flg.csv',result_flg);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.image = uiaxes(app.UIFigure);
            title(app.image, 'Title')
            xlabel(app.image, 'X')
            ylabel(app.image, 'Y')
            zlabel(app.image, 'Z')
            app.image.ButtonDownFcn = createCallbackFcn(app, @imageButtonDown, true);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'  1. 单击导入数据按钮，选择计算进行计算的数据文件。'; '  2. 点击计算按钮。'; '  3. 计算完毕后会得到行走步数以及平均步长，同时给出每步的步长。'};
            app.Cac = uibutton(app.UIFigure, 'push');
            app.Cac.ButtonPushedFcn = createCallbackFcn(app, @CacButtonPushed, true);
            app.Cac.Text = '计算步数';
            app.step_num = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '行走步数';
            app.LoadData = uibutton(app.UIFigure, 'push');
            app.LoadData.ButtonPushedFcn = createCallbackFcn(app, @LoadDataPushed, true);
            app.LoadData.Text = '导入数据';
            app.DatePicker = uidatepicker(app.UIFigure);
            app.DatePicker.DisplayFormat = 'uuuu/MM/dd';
            app.DatePicker.Value = datetime([2023 4 18]);
            app.OutPut = uibutton(app.UIFigure, 'push');
            app.OutPut.ButtonPushedFcn = createCallbackFcn(app, @OutPutPushed, true);
            app.OutPut.Text = '输出';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '日期';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '交互模块';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ourApplication

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end